classdef drt_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DuneResponseToolUIFigure      matlab.ui.Figure
        DuneResponseToolLabel         matlab.ui.control.Label
        RunModelButton                matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        InfoButton                    matlab.ui.control.Button
        SaveOutputButton              matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ModelAttributesTab            matlab.ui.container.Tab
        ClicktoManuallyChooseLocationonMapButton  matlab.ui.control.Button
        LocationLabel                 matlab.ui.control.Label
        MorphologyLabel               matlab.ui.control.Label
        UpdateMorphometricsButton     matlab.ui.control.Button
        TimingLabel                   matlab.ui.control.Label
        DurationdaysEditFieldLabel    matlab.ui.control.Label
        DurationdaysEditField         matlab.ui.control.NumericEditField
        StartDateDatePickerLabel      matlab.ui.control.Label
        StartDateDatePicker           matlab.ui.control.DatePicker
        LatitudeEditFieldLabel        matlab.ui.control.Label
        LatitudeEditField             matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel       matlab.ui.control.Label
        LongitudeEditField            matlab.ui.control.NumericEditField
        DuneCrestElevmEditFieldLabel  matlab.ui.control.Label
        DuneCrestElevmEditField       matlab.ui.control.NumericEditField
        DuneToeElevmEditFieldLabel    matlab.ui.control.Label
        DuneToeElevmEditField         matlab.ui.control.NumericEditField
        DuneSlopemmEditFieldLabel     matlab.ui.control.Label
        DuneSlopemmEditField          matlab.ui.control.NumericEditField
        BeachSlopemmEditFieldLabel    matlab.ui.control.Label
        BeachSlopemmEditField         matlab.ui.control.NumericEditField
        ShoreNormaldegEditFieldLabel  matlab.ui.control.Label
        ShoreNormaldegEditField       matlab.ui.control.NumericEditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        Hindcast19802017Button        matlab.ui.control.RadioButton
        ForecastnowButton             matlab.ui.control.RadioButton
        JALBTCXButton                 matlab.ui.control.Button
        AdvancedTab                   matlab.ui.container.Tab
        ModelParametersLabel          matlab.ui.control.Label
        MedianGrainSizemmEditFieldLabel  matlab.ui.control.Label
        MedianGrainSizemmEditField    matlab.ui.control.NumericEditField
        WaveRunupFactorLabel          matlab.ui.control.Label
        WaveRunupFactorEditField      matlab.ui.control.NumericEditField
        DuneSlopeTrajectoryEditFieldLabel  matlab.ui.control.Label
        DuneSlopeTrajectoryEditField  matlab.ui.control.NumericEditField
        DuneErodibilityCoefficientEditFieldLabel  matlab.ui.control.Label
        DuneErodibilityCoefficientEditField  matlab.ui.control.NumericEditField
        AeolianTransportCoefficientEditFieldLabel  matlab.ui.control.Label
        AeolianTransportCoefficientEditField  matlab.ui.control.NumericEditField
        ModelTypeLabel                matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        DeterministicCaseButton       matlab.ui.control.RadioButton
        EnsembleRunButton             matlab.ui.control.RadioButton
        VariableDropDownLabel         matlab.ui.control.Label
        VariableDropDown              matlab.ui.control.DropDown
        MinValueEditFieldLabel        matlab.ui.control.Label
        MinValueEditField             matlab.ui.control.NumericEditField
        MaxValueEditFieldLabel        matlab.ui.control.Label
        MaxValueEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
        %scenario
    end
    
    properties (Access = public)
        scenario % Description
        scenarioHigh
        scenarioLow
    end
    
    methods (Access = private)
        
        
        function scenario = drt_search_morphology(app)
            scenario = app.scenario;
            
            %calc distance away of all points from target point
            distances = sqrt((scenario.morph.all_morph.Lon(:)-scenario.location.lon).^2 + (scenario.morph.all_morph.Lat(:)-scenario.location.lat).^2);

            [minval, imin] = nanmin(distances);
            
            if minval > 1 %dont consider a node more than 5 degrees away
                f = msgbox('No pre-loaded morphology data close to the selected site', 'Error','error');
            end
                                  
            %save relevant values
            scenario.grids.morphometrics.dhigh = scenario.morph.all_morph.DuneCrestElev_m_navd(imin);
            scenario.grids.morphometrics.dtoe =  scenario.morph.all_morph.DuneToeElev_m_navd(imin);
            scenario.grids.morphometrics.duneslope =  scenario.morph.all_morph.DuneSlope(imin);
            if scenario.grids.morphometrics.duneslope > 1
                scenario.grids.morphometrics.duneslope = 1; %dont let it go past the angle of repose
            end
            scenario.grids.morphometrics.backshoreslope = scenario.morph.all_morph.BeachSlope(imin);
            scenario.grids.morphometrics.azimuth =  scenario.morph.all_morph.Azimuth(imin);
        end
        
        function [out_lat, out_lon] = drt_choose_latlon(app)
            
            in_lat = app.scenario.morph.all_morph.Lat(1:100:end); 
            in_lon = app.scenario.morph.all_morph.Lon(1:100:end);
            
            
            fig = figure;
            gx = geoaxes;
            h = geoscatter(gx,in_lat,in_lon, 100, 'r.');
            legend(h(1), 'Morphology Data Available', 'Location', 'NorthWest')
            geobasemap(gx, 'satellite')
            %set(fig, 'WindowButtonMotionFcn', @(x,y) moveMouse(x,y,gx));
            w = waitforbuttonpress;
            pt = gx.CurrentPoint;
        
            out_lat = pt(1,1);
            out_lon = pt(1,2);
            close(fig)
        end
        
        function moveMouse(~, ~, handle)
            C = handle.CurrentPoint;
            title(gca, ['Longitude = ', num2str(C(1,1)), ', Latitude = ',num2str(C(1,2))]);
            drawnow
       end
        
        function scenario = run_drt(app)
                scenario = app.scenario;
                
                %Build Morphology Grid
                scenario = drt_grid(scenario);
            
                %run simulation
                scenario = drt_erosion(scenario);  
                scenario = drt_accretion(scenario);            
          
        end
        
       function [dcrest, dtoe] = drt_search_jalbtcx(app)
            scenario = app.scenario;
                        
            %extents
            miny=app.LatitudeEditField.Value-0.2;
            maxy=app.LatitudeEditField.Value+0.2;
            minx=app.LongitudeEditField.Value-0.2;
            maxx=app.LongitudeEditField.Value+0.2;

            %find nearby data
            url = ['http://arcgis.usacegis.com/arcgis/rest/services/JALBTCX/JALBTCX_Geomorphic_Features/FeatureServer/4/query?where=&objectIds=&time=&geometry=', num2str(minx),'%2C', num2str(miny),'%2C', num2str(maxx),'%2C', num2str(maxy),'&geometryType=esriGeometryEnvelope&inSR=&spatialRel=esriSpatialRelIntersects&distance=&units=esriSRUnit_Foot&relationParam=&outFields=Type%2C+Beach_Width%2C+Crest_Height%2C+Toe_Height%2C+Year%2C+Effort&returnGeometry=true&maxAllowableOffset=&geometryPrecision=&outSR=&havingClause=&gdbVersion=&historicMoment=&returnDistinctValues=false&returnIdsOnly=false&returnCountOnly=false&returnExtentOnly=false&orderByFields=&groupByFieldsForStatistics=&outStatistics=&returnZ=false&returnM=false&multipatchOption=xyFootprint&resultOffset=&resultRecordCount=&returnTrueCurves=false&returnExceededLimitFeatures=false&quantizationParameters=&returnCentroid=false&sqlFormat=none&resultType=&featureEncoding=esriDefault&datumTransformation=&f=html'];
            options = weboptions;
            options.Timeout = 200;
            data = webread(url, options);
            
            try
            
                %subset data
                ibw = strfind(data,'Beach_Width:');
                count = 0;
                clear bw
                for idx = 1:numel(ibw)
                    count = count+1;
                    temp = data([ibw(idx):ibw(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        bw(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        bw(count) = NaN;
                    end   
                end
                
    
                idc= strfind(data,'Crest_Height:');
                count = 0;
                clear dc
                for idx = 1:numel(idc)
                    count = count+1;
                    temp = data([idc(idx):idc(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        dc(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        dc(count) = NaN;
                    end   
                end
                
                
                idh= strfind(data,'Toe_Height:');
                count = 0;
                clear dh
                for idx = 1:numel(idh)
                    count = count+1;
                    temp = data([idh(idx):idh(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        dh(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        dh(count) = NaN;
                    end   
                end
                
                idy= strfind(data,'Year:');
                count = 0;
                clear yr
                for idx = 1:numel(idy)
                    count = count+1;
                    temp = data([idy(idx):idy(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        yr(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        yr(count) = NaN;
                    end   
                end
                    
                idLat= strfind(data,'Y:');
                count = 0;
                clear Lat
                for idx = 1:numel(idLat)
                    count = count+1;
                    temp = data([idLat(idx):idLat(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        Lat(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        Lat(count) = NaN;
                    end   
                end
                          
                idLon= strfind(data,'X:');
                count = 0;
                clear Lon
                for idx = 1:numel(idLon)
                    count = count+1;
                    temp = data([idLon(idx):idLon(idx)+33]);
                    [idxs] = regexp(temp,'[0-9]');
                    
                    try
                        Lon(count) = str2num(temp([min(idxs):1:max(idxs)]));
                    catch err
                        Lon(count) = NaN;
                    end   
                end
                
                
                distances = sqrt((Lon(:)-app.LongitudeEditField.Value).^2 + (Lat(:)-app.LatitudeEditField.Value).^2);
                [minval, imin] = nanmin(distances);
                
                if minval < 10000
                    dcrest = dc(imin);
                    dtoe = dh(imin);
                else
                    dcrest = NaN;
                    dtoe = NaN;
                end            
            catch err
                    dcrest = NaN;
                    dtoe = NaN;                         
            end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %go to directory that the script is in
            mfile_name = mfilename('fullpath');
            [pathstr,name,ext]  = fileparts(mfile_name);
            cd(pathstr);
            addpath(genpath(pathstr));
            
            %load relevant morphology data
            app.scenario.morph.all_morph = readtable('drt_morphology.xlsx');
            
        end

        % Button pushed function: 
        % ClicktoManuallyChooseLocationonMapButton
        function ClicktoManuallyChooseLocationonMapButtonPushed(app, event)
            [out_lat, out_lon] = drt_choose_latlon(app);
            app.scenario.morph.pt_lat = out_lat;
            app.scenario.morph.pt_lon = out_lon;   
            
            app.LatitudeEditField.Value = out_lat;
            app.LongitudeEditField.Value = out_lon;
        end

        % Button pushed function: UpdateMorphometricsButton
        function UpdateMorphometricsButtonPushed(app, event)
            %Search Datasets Using Input Lat/Lon Data
            app.scenario.location.lat = app.LatitudeEditField.Value;
            app.scenario.location.lon = app.LongitudeEditField.Value;
            app.scenario = drt_search_morphology(app);
            
            %Update Text Boxes
            app.DuneCrestElevmEditField.Value = app.scenario.grids.morphometrics.dhigh;
            app.DuneToeElevmEditField.Value = app.scenario.grids.morphometrics.dtoe;
            app.DuneSlopemmEditField.Value = app.scenario.grids.morphometrics.duneslope;
            app.BeachSlopemmEditField.Value = app.scenario.grids.morphometrics.backshoreslope;
            app.ShoreNormaldegEditField.Value = app.scenario.grids.morphometrics.azimuth;
            
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            app.scenario.location.lat = app.LatitudeEditField.Value;
            app.scenario.location.lon = app.LongitudeEditField.Value;

            %update scenario morphometrics in case values changes in text boxes
            app.scenario.grids.morphometrics.dhigh = app.DuneCrestElevmEditField.Value; 
            app.scenario.grids.morphometrics.dtoe = app.DuneToeElevmEditField.Value;
            app.scenario.grids.morphometrics.duneslope = app.DuneSlopemmEditField.Value;
            app.scenario.grids.morphometrics.backshoreslope = app.BeachSlopemmEditField.Value;
            app.scenario.grids.morphometrics.azimuth = app.ShoreNormaldegEditField.Value; 
                         
            if app.Hindcast19802017Button.Value
                app.scenario.timing.start_date = datenum(app.StartDateDatePicker.Value);
                app.scenario.timing.end_date = app.scenario.timing.start_date+app.DurationdaysEditField.Value;
                app.scenario.timing.dt = 1;%in hours
                app.scenario.type = 'hindcast'; 
            else
                %add the dates later for the forecast version
                app.scenario.timing.dt = 1; %in hours
                app.scenario.type = 'forecast';
            end
            
            %update free model parameters
            app.scenario.models.d50 = app.MedianGrainSizemmEditField.Value;
            app.scenario.models.WaveRunupFactor = app.WaveRunupFactorEditField.Value;
            app.scenario.models.DuneSlopeTrajectory = app.DuneSlopeTrajectoryEditField.Value;
            app.scenario.models.DuneErodibility = app.DuneErodibilityCoefficientEditField.Value;
            app.scenario.models.AeolianTransportCoefficient = app.AeolianTransportCoefficientEditField.Value;
                           
            %Download environmental data
            f = waitbar(0.1,'Downloading Environmental Data');
            app.scenario = drt_env(app.scenario);  
            
            app.DeterministicCaseButton
            
            if app.DeterministicCaseButton.Value == 1 
               waitbar(0.65, f,'Running Model');
               app.scenario = run_drt(app);
                            
            elseif strcmp(app.VariableDropDown.Value, 'Beach Slope') == 1               
               waitbar(0.65, f,'Running Ensemble Model - Base Case');                      
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.grids.morphometrics.backshoreslope = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.grids.morphometrics.backshoreslope = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);

            elseif strcmp(app.VariableDropDown.Value, 'Dune Toe Elev') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');            
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.grids.morphometrics.dtoe = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.grids.morphometrics.dtoe = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);
               
            elseif strcmp(app.VariableDropDown.Value, 'Dune Crest Elev') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');             
               app.scenario1 = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.grids.morphometrics.dhigh = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.grids.morphometrics.dhigh = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);
               
            elseif strcmp(app.VariableDropDown.Value, 'Dune Slope') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');             
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.grids.morphometrics.duneslope = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.grids.morphometrics.duneslope = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);               
                
            elseif strcmp(app.VariableDropDown.Value, 'Wave Runup Factor') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');             
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.models.WaveRunupFactor = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.models.WaveRunupFactor = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);
               
            elseif strcmp(app.VariableDropDown.Value, 'Dune Slope Trajectory') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');             
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.models.DuneSlopeTrajectory = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.models.DuneSlopeTrajectory = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);
               
            elseif strcmp(app.VariableDropDown.Value, 'Dune Erodibility Coefficient') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');             
               app.scenario = run_drt(app);
               
               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.models.DuneErodibility = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);
               
               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.models.DuneErodibility = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);
                    
               
            elseif strcmp(app.VariableDropDown.Value, 'Aeolian Transport Coefficient') == 1 
               waitbar(0.65, f,'Running Ensemble Model - Base Case');      
               app.scenario = run_drt(app);

               waitbar(0.75, f,'Running Ensemble Model - Low Case');      
               app.scenario.models.AeolianTransportCoefficient = app.MinValueEditField.Value;
               app.scenarioLow = run_drt(app);

               waitbar(0.85, f,'Running Ensemble Model - High Case');      
               app.scenario.models.AeolianTransportCoefficient = app.MaxValueEditField.Value;              
               app.scenarioHigh = run_drt(app);               
               
            end
                                    
            %plot outputs
            waitbar(0.95, f,'Plotting Data');
             if app.DeterministicCaseButton.Value == 1                                  
                drt_plotting(app.scenario);
             else
                drt_plotting_ensemble(app.scenario, app.scenarioLow, app.scenarioHigh);               
             end
            close(f)     
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
                message1 = ['This is an experimental product for the rapid simulation of coastal foredune evolution during storms funded by the ERDC Coastal Inlets Research Program'];
                message2 = [''];
                message3 = [''];
                message4 = ['Models: Dune Profile Erosion Simulation based on Palmsten and Holman (2012), Aeolian Transport Simulation based on Kawamura (1951) and Delgado-Fernandez (2011)'];
                message5 = [' '];
                message6 = [' '];
                message7 = ['Morphology Data: Dune and beach slope morphometrics are generated from lidar-derived from the USGS Geological Survey (https://coastal.er.usgs.gov/data-release/doi-F7GF0S0Z/) and from Dr. Peter Ruggiero (Oregon State University)'];
                message8 = [' '];
                message9 = [' '];
                message10 = ['Wave/Wind Data: Hindcast Data is from the USACE Wave Information Studies, Forecast Data is from NOAA WaveWatchIII'];
                message11 = [' '];
                message12 = [' '];
                message13 = ['Tide Data: Historical Data is from NOAA Tides and Current, Surge Forecast is from ESTOFS'];
                message14 = [' '];
                message15 = [' '];
                message16 = [' Note that web sourced environmental data services do not have 100% uptime and may result in unsuccessful model runs.'];
                message17 = [' '];
                message18 = [' '];                
                message19 = ['For questions on this tool contact Nick Cohn (nicholas.t.cohn@erdc.dren.mil)'];
                
                f = msgbox({message1;message2; message3; message4; message5; message6; message7; message8; message9; message10; message11; message12; message13; message14; message15; message16; message17; message18; message19}); 
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
                selpath = uigetdir;
                
                output_file = [selpath, filesep, 'dunetool_output.nc'];
                delete(output_file); %get rid of existing data first
                
               
                %create variables to output
                %timing
                time = app.scenario.timing.times; %converted to seconds
                
                %env
                WL = app.scenario.env.tides.wl;
                Ho = app.scenario.env.waves.Hs_25m;
                Lo = app.scenario.env.waves.L_25m;
                T = app.scenario.env.waves.Tp;
                u_w = app.scenario.env.winds.windSpeed;
                windDir = app.scenario.env.winds.windDirection;
                
                %profile and morphometrics
                Bo = app.scenario.grids.morphometrics.backshoreslope;
                ztoe = app.scenario.grids.morphometrics.dtoe;
                zcrest = app.scenario.grids.morphometrics.dhigh;
                duneslope = app.scenario.grids.morphometrics.duneslope;
                azimuth = app.scenario.grids.morphometrics.azimuth;
               
                
                %outputs
                accretion = cumsum(app.scenario.accretion.dV);
                erosion = -cumsum(app.scenario.erosion.dV);
                net = accretion+erosion;
    
                %create new netcdf file
                nccreate(output_file, 'time', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'stillWaterLevel', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'sigWaveHeight', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'wavePeriod', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'windSpeed', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'windDir', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'backshoreSlope');
                nccreate(output_file, 'duneSlope');
                nccreate(output_file, 'zToe');
                nccreate(output_file, 'zCrest');
                nccreate(output_file, 'azimuth');
                nccreate(output_file, 'accretionVolume', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'erosionVolume', 'Dimensions', {'t', numel(time)});
                nccreate(output_file, 'netVolumeChange', 'Dimensions', {'t', numel(time)});
              
                %populate netcdf file
                ncwrite(output_file, 'time', time)    
                ncwrite(output_file, 'stillWaterLevel', WL)    
                ncwrite(output_file, 'sigWaveHeight', Ho)    
                ncwrite(output_file, 'wavePeriod', T)    
                ncwrite(output_file, 'windSpeed', u_w)    
                ncwrite(output_file, 'windDir', windDir)    
                ncwrite(output_file, 'backshoreSlope', Bo)    
                ncwrite(output_file, 'duneSlope', duneslope)    
                ncwrite(output_file, 'zToe', zToe)    
                ncwrite(output_file, 'zCrest', zCrest)    
                ncwrite(output_file, 'azimith', azimuth)    
                ncwrite(output_file, 'accretionVolume', accretion)    
                ncwrite(output_file, 'erosionVolume', erosion)    
                ncwrite(output_file, 'netVolumeChange', net)    
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if app.Hindcast19802017Button.Value
                app.StartDateDatePicker.BackgroundColor = [1 1 1];
                app.DurationdaysEditField.BackgroundColor = [1 1 1];                
            elseif app.ForecastnowButton.Value
                app.StartDateDatePicker.BackgroundColor = [0 0 0];
                app.DurationdaysEditField.BackgroundColor = [0 0 0];
            end
            
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            value = app.VariableDropDown.Value;
            
            if strcmp(value, 'Beach Slope')
                app.MinValueEditField.Value = app.BeachSlopemmEditField.Value*0.8;
                app.MaxValueEditField.Value = app.BeachSlopemmEditField.Value*1.2;
            elseif strcmp(value, 'Dune Toe Elev')
                app.MinValueEditField.Value = app.DuneToeElevmEditField.Value*0.8;
                app.MaxValueEditField.Value = app.DuneToeElevmEditField.Value*1.2;                            
            elseif strcmp(value, 'Dune Slope')
                app.MinValueEditField.Value = app.DuneSlopemmEditField.Value*0.8;
                app.MaxValueEditField.Value = app.DuneSlopemmEditField.Value*1.2;                    
            elseif strcmp(value, 'Dune Crest Elev')
                app.MinValueEditField.Value = app.DuneCrestElevmEditField.Value*0.8;
                app.MaxValueEditField.Value = app.DuneCrestElevmEditField.Value*1.2;                    
            elseif strcmp(value, 'Wave Runup Factor')
                app.MinValueEditField.Value = 1;
                app.MaxValueEditField.Value = 1.5;                    
            elseif strcmp(value, 'Dune Slope Trajectory')
                app.MinValueEditField.Value = app.DuneSlopeTrajectoryEditField.Value*0.8;
                app.MaxValueEditField.Value = app.DuneSlopeTrajectoryEditField.Value*1.2;    
            elseif strcmp(value, 'Dune Erodibility Coefficient')
                app.MinValueEditField.Value = app.DuneErodibilityCoefficientEditField.Value*0.8;
                app.MaxValueEditField.Value = app.DuneErodibilityCoefficientEditField.Value*1.2;    
            elseif strcmp(value, 'Aeolian Transport Coefficient')
                app.MinValueEditField.Value = app.AeolianTransportCoefficientEditField.Value*0.8;
                app.MaxValueEditField.Value = app.AeolianTransportCoefficientEditField.Value*1.2;    
            end                    
        end

        % Value changed function: BeachSlopemmEditField
        function updateEnsembleBeachSlope(app, event)
          
            value = app.VariableDropDown.Value;          
            if strcmp(value, 'Beach Slope') == 1
                app.MinValueEditField.Value = app.BeachSlopemmEditField.Value*0.8;
                app.MaxValueEditField.Value = app.BeachSlopemmEditField.Value*1.2;
            end
                   
        end

        % Button pushed function: JALBTCXButton
        function JALBTCXButtonPushed(app, event)
            
            %search JALBTCX Data
            [dcrest, dtoe] = drt_search_jalbtcx(app);
            
               
            if isnan(dcrest) == 0
                    app.DuneCrestElevmEditField.Value = dcrest;
                    app.scenario.grids.morphometrics.dhigh = dcrest;
            end
                
            if isnan(dtoe) == 0
                    app.DuneToeElevmEditField.Value = dtoe;
                    app.scenario.grids.morphometrics.dtoe = dtoe;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DuneResponseToolUIFigure = uifigure('Visible', 'off');
            app.DuneResponseToolUIFigure.Name = 'Dune Response Tool';
            app.DuneResponseToolLabel = uilabel(app.DuneResponseToolUIFigure);
            app.DuneResponseToolLabel.Text = {'Dune '; 'Response'; 'Tool'};
            app.RunModelButton = uibutton(app.DuneResponseToolUIFigure, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = {'Run '; 'Model '; ''};
            app.Image = uiimage(app.DuneResponseToolUIFigure);
            app.Image.ImageSource = 'cirp-logo.png';
            app.Image2 = uiimage(app.DuneResponseToolUIFigure);
            app.Image2.ImageSource = 'erdc-logo.png';
            app.InfoButton = uibutton(app.DuneResponseToolUIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.SaveOutputButton = uibutton(app.DuneResponseToolUIFigure, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = {'Save'; 'Output'; ''};
            app.TabGroup = uitabgroup(app.DuneResponseToolUIFigure);
            app.ModelAttributesTab = uitab(app.TabGroup);
            app.ModelAttributesTab.Title = 'Model Attributes';
            app.ClicktoManuallyChooseLocationonMapButton = uibutton(app.ModelAttributesTab, 'push');
            app.ClicktoManuallyChooseLocationonMapButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoManuallyChooseLocationonMapButtonPushed, true);
            app.ClicktoManuallyChooseLocationonMapButton.Text = {'Click to Manually '; 'Choose Location'; 'on Map'};
            app.LocationLabel = uilabel(app.ModelAttributesTab);
            app.LocationLabel.Text = 'Location';
            app.MorphologyLabel = uilabel(app.ModelAttributesTab);
            app.MorphologyLabel.Text = 'Morphology ';
            app.UpdateMorphometricsButton = uibutton(app.ModelAttributesTab, 'push');
            app.UpdateMorphometricsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMorphometricsButtonPushed, true);
            app.UpdateMorphometricsButton.Text = {'Click to Update '; 'Morphology'; 'Based on '; 'Lat/Lon '; ' from Local '; 'Database'};
            app.TimingLabel = uilabel(app.ModelAttributesTab);
            app.TimingLabel.Text = 'Timing ';
            app.DurationdaysEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.DurationdaysEditFieldLabel.Text = 'Duration (days)';
            app.DurationdaysEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.DurationdaysEditField.Limits = [0.01 Inf];
            app.DurationdaysEditField.Value = 7;
            app.StartDateDatePickerLabel = uilabel(app.ModelAttributesTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.ModelAttributesTab);
            app.StartDateDatePicker.Limits = [datetime([1980 1 1]) datetime([2017 12 29])];
            app.StartDateDatePicker.Value = datetime([2012 10 25]);
            app.LatitudeEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.LatitudeEditField.Limits = [-90 90];
            app.LatitudeEditField.Value = 36.183;
            app.LongitudeEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.LongitudeEditField.Limits = [-180 180];
            app.LongitudeEditField.Value = -75.75;
            app.DuneCrestElevmEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.DuneCrestElevmEditFieldLabel.Text = 'Dune Crest Elev. (m)';
            app.DuneCrestElevmEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.DuneCrestElevmEditField.Limits = [0.1 20];
            app.DuneCrestElevmEditField.ValueDisplayFormat = '%11.2g';
            app.DuneCrestElevmEditField.Value = 6;
            app.DuneToeElevmEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.DuneToeElevmEditFieldLabel.Text = 'Dune Toe Elev. (m)';
            app.DuneToeElevmEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.DuneToeElevmEditField.Limits = [0 10];
            app.DuneToeElevmEditField.ValueDisplayFormat = '%11.2g';
            app.DuneToeElevmEditField.Value = 3;
            app.DuneSlopemmEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.DuneSlopemmEditFieldLabel.Text = 'Dune Slope (m/m)';
            app.DuneSlopemmEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.DuneSlopemmEditField.Limits = [0.01 0.6];
            app.DuneSlopemmEditField.ValueDisplayFormat = '%11.3g';
            app.DuneSlopemmEditField.Value = 0.2;
            app.BeachSlopemmEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.BeachSlopemmEditFieldLabel.Text = 'Beach Slope (m/m)';
            app.BeachSlopemmEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.BeachSlopemmEditField.Limits = [0.01 0.5];
            app.BeachSlopemmEditField.ValueDisplayFormat = '%11.3g';
            app.BeachSlopemmEditField.ValueChangedFcn = createCallbackFcn(app, @updateEnsembleBeachSlope, true);
            app.BeachSlopemmEditField.Value = 0.1;
            app.ShoreNormaldegEditFieldLabel = uilabel(app.ModelAttributesTab);
            app.ShoreNormaldegEditFieldLabel.Text = 'Shore Normal (deg.)';
            app.ShoreNormaldegEditField = uieditfield(app.ModelAttributesTab, 'numeric');
            app.ShoreNormaldegEditField.Limits = [-180 360];
            app.ShoreNormaldegEditField.ValueDisplayFormat = '%.1f';
            app.ShoreNormaldegEditField.Value = 70;
            app.ButtonGroup = uibuttongroup(app.ModelAttributesTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Hindcast19802017Button = uiradiobutton(app.ButtonGroup);
            app.Hindcast19802017Button.Text = 'Hindcast (1980 - 2017)';
            app.Hindcast19802017Button.Value = true;
            app.ForecastnowButton = uiradiobutton(app.ButtonGroup);
            app.ForecastnowButton.Text = 'Forecast (now)';
            app.JALBTCXButton = uibutton(app.ModelAttributesTab, 'push');
            app.JALBTCXButton.ButtonPushedFcn = createCallbackFcn(app, @JALBTCXButtonPushed, true);
            app.JALBTCXButton.Text = {'Click to Update with '; 'Online JALBTCX '; 'Data (if available)'};
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.ModelParametersLabel = uilabel(app.AdvancedTab);
            app.ModelParametersLabel.Text = 'Model Parameters';
            app.MedianGrainSizemmEditFieldLabel = uilabel(app.AdvancedTab);
            app.MedianGrainSizemmEditFieldLabel.Text = 'Median Grain Size (mm)';
            app.MedianGrainSizemmEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MedianGrainSizemmEditField.Limits = [0.05 2];
            app.MedianGrainSizemmEditField.ValueDisplayFormat = '%11.2g';
            app.MedianGrainSizemmEditField.Value = 0.35;
            app.WaveRunupFactorLabel = uilabel(app.AdvancedTab);
            app.WaveRunupFactorLabel.Text = 'Wave Runup Factor';
            app.WaveRunupFactorEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.WaveRunupFactorEditField.Limits = [1 2];
            app.WaveRunupFactorEditField.ValueDisplayFormat = '%11.3g';
            app.WaveRunupFactorEditField.Value = 1.26;
            app.DuneSlopeTrajectoryEditFieldLabel = uilabel(app.AdvancedTab);
            app.DuneSlopeTrajectoryEditFieldLabel.Text = 'Dune Slope Trajectory';
            app.DuneSlopeTrajectoryEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.DuneSlopeTrajectoryEditField.Limits = [0.05 2];
            app.DuneSlopeTrajectoryEditField.Value = 0.54;
            app.DuneErodibilityCoefficientEditFieldLabel = uilabel(app.AdvancedTab);
            app.DuneErodibilityCoefficientEditFieldLabel.Text = 'Dune Erodibility Coefficient';
            app.DuneErodibilityCoefficientEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.DuneErodibilityCoefficientEditField.Limits = [1e-06 1];
            app.DuneErodibilityCoefficientEditField.ValueDisplayFormat = '%11.2g';
            app.DuneErodibilityCoefficientEditField.Value = 0.0025;
            app.AeolianTransportCoefficientEditFieldLabel = uilabel(app.AdvancedTab);
            app.AeolianTransportCoefficientEditFieldLabel.Text = 'Aeolian Transport Coefficient';
            app.AeolianTransportCoefficientEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.AeolianTransportCoefficientEditField.Limits = [1e-06 10];
            app.AeolianTransportCoefficientEditField.ValueDisplayFormat = '%11.3g';
            app.AeolianTransportCoefficientEditField.Value = 2.78;
            app.ModelTypeLabel = uilabel(app.AdvancedTab);
            app.ModelTypeLabel.Text = 'Model Type';
            app.ButtonGroup_2 = uibuttongroup(app.AdvancedTab);
            app.DeterministicCaseButton = uiradiobutton(app.ButtonGroup_2);
            app.DeterministicCaseButton.Text = 'Deterministic Case';
            app.DeterministicCaseButton.Value = true;
            app.EnsembleRunButton = uiradiobutton(app.ButtonGroup_2);
            app.EnsembleRunButton.Text = 'Ensemble Run';
            app.VariableDropDownLabel = uilabel(app.ButtonGroup_2);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.ButtonGroup_2);
            app.VariableDropDown.Items = {'Beach Slope', 'Dune Toe Elev', 'Dune Crest Elev', 'Dune Slope', 'Wave Runup Factor', 'Dune Slope Trajectory', 'Dune Erodibility Coefficient', 'Aeolian Transport Coefficient'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'Beach Slope';
            app.MinValueEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.MinValueEditFieldLabel.Text = 'Min. Value';
            app.MinValueEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.MinValueEditField.Limits = [0 Inf];
            app.MinValueEditField.ValueDisplayFormat = '%11.3g';
            app.MinValueEditField.Value = 0.05;
            app.MaxValueEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.MaxValueEditFieldLabel.Text = 'Max. Value';
            app.MaxValueEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.MaxValueEditField.Limits = [0 Inf];
            app.MaxValueEditField.ValueDisplayFormat = '%11.3g';
            app.MaxValueEditField.Value = 0.2;
            % Show the figure after all components are created
            app.DuneResponseToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drt_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DuneResponseToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DuneResponseToolUIFigure)
        end
    end
end